classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Menu               matlab.ui.container.Menu
        Menu_2             matlab.ui.container.Menu
        Menu_3             matlab.ui.container.Menu
        Menu_4             matlab.ui.container.Menu
        Menu_5             matlab.ui.container.Menu
        Menu_6             matlab.ui.container.Menu
        Menu_7             matlab.ui.container.Menu
        Menu_8             matlab.ui.container.Menu
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        TabGroup2          matlab.ui.container.TabGroup
        Tab_4              matlab.ui.container.Tab
        Panel_10           matlab.ui.container.Panel
        GridLayout8        matlab.ui.container.GridLayout
        EditField_72       matlab.ui.control.NumericEditField
        Label_53           matlab.ui.control.Label
        EditField_71       matlab.ui.control.NumericEditField
        EditField_71Label  matlab.ui.control.Label
        EditField_70       matlab.ui.control.NumericEditField
        Label_52           matlab.ui.control.Label
        Button_draw        matlab.ui.control.Button
        Button_check       matlab.ui.control.Button
        Panel_1            matlab.ui.container.Panel
        DropDown_4         matlab.ui.control.DropDown
        Label_25           matlab.ui.control.Label
        DropDown_3         matlab.ui.control.DropDown
        Label_24           matlab.ui.control.Label
        DropDown_2         matlab.ui.control.DropDown
        Label_23           matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        Label_22           matlab.ui.control.Label
        Panel_2            matlab.ui.container.Panel
        GridLayout         matlab.ui.container.GridLayout
        EditField_1        matlab.ui.control.NumericEditField
        Label_11           matlab.ui.control.Label
        EditField_7        matlab.ui.control.NumericEditField
        EditField_6        matlab.ui.control.NumericEditField
        EditField_8        matlab.ui.control.NumericEditField
        EditField_5        matlab.ui.control.NumericEditField
        EditField_4        matlab.ui.control.NumericEditField
        EditField_9        matlab.ui.control.NumericEditField
        EditField_3        matlab.ui.control.NumericEditField
        EditField_2        matlab.ui.control.NumericEditField
        EditField_10       matlab.ui.control.NumericEditField
        Label_34           matlab.ui.control.Label
        Label_33           matlab.ui.control.Label
        Label_32           matlab.ui.control.Label
        Label_31           matlab.ui.control.Label
        Label_30           matlab.ui.control.Label
        Label_29           matlab.ui.control.Label
        Label_28           matlab.ui.control.Label
        Label_27           matlab.ui.control.Label
        Label_26           matlab.ui.control.Label
        Panel_3            matlab.ui.container.Panel
        GridLayout2        matlab.ui.container.GridLayout
        EditField_16       matlab.ui.control.NumericEditField
        EditField_15       matlab.ui.control.NumericEditField
        EditField_17       matlab.ui.control.NumericEditField
        EditField_14       matlab.ui.control.NumericEditField
        EditField_13       matlab.ui.control.NumericEditField
        EditField_18       matlab.ui.control.NumericEditField
        EditField_12       matlab.ui.control.NumericEditField
        EditField_11       matlab.ui.control.NumericEditField
        EditField_19       matlab.ui.control.NumericEditField
        Label_41           matlab.ui.control.Label
        Label_40           matlab.ui.control.Label
        Label_39           matlab.ui.control.Label
        Label_38           matlab.ui.control.Label
        Label_37           matlab.ui.control.Label
        MDLabel            matlab.ui.control.Label
        Label_36           matlab.ui.control.Label
        Label_35           matlab.ui.control.Label
        Label_21           matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        Tab_5              matlab.ui.container.Tab
        Slider             matlab.ui.control.Slider
        Label_51           matlab.ui.control.Label
        Button             matlab.ui.control.Button
        Panel_6            matlab.ui.container.Panel
        DropDown_6         matlab.ui.control.DropDown
        Label_43           matlab.ui.control.Label
        DropDown_5         matlab.ui.control.DropDown
        Label_42           matlab.ui.control.Label
        Panel_4            matlab.ui.container.Panel
        GridLayout3        matlab.ui.container.GridLayout
        EditField_58       matlab.ui.control.NumericEditField
        EditField_58Label  matlab.ui.control.Label
        EditField_57       matlab.ui.control.NumericEditField
        Label_47           matlab.ui.control.Label
        EditField_56       matlab.ui.control.NumericEditField
        EditField_56Label  matlab.ui.control.Label
        EditField_55       matlab.ui.control.NumericEditField
        EditField_55Label  matlab.ui.control.Label
        EditField_54       matlab.ui.control.NumericEditField
        EditField_54Label  matlab.ui.control.Label
        EditField_53       matlab.ui.control.NumericEditField
        Label_46           matlab.ui.control.Label
        EditField_52       matlab.ui.control.NumericEditField
        Label_45           matlab.ui.control.Label
        EditField_51       matlab.ui.control.NumericEditField
        EditField_51Label  matlab.ui.control.Label
        EditField_50       matlab.ui.control.NumericEditField
        EditField_50Label  matlab.ui.control.Label
        EditField_49       matlab.ui.control.NumericEditField
        Label_44           matlab.ui.control.Label
        Panel_5            matlab.ui.container.Panel
        GridLayout4        matlab.ui.container.GridLayout
        EditField_69       matlab.ui.control.NumericEditField
        Label_50           matlab.ui.control.Label
        EditField_68       matlab.ui.control.NumericEditField
        Label_49           matlab.ui.control.Label
        EditField_67       matlab.ui.control.NumericEditField
        EditField_67Label  matlab.ui.control.Label
        EditField_66       matlab.ui.control.NumericEditField
        EditField_66Label  matlab.ui.control.Label
        EditField_65       matlab.ui.control.NumericEditField
        EditField_65Label  matlab.ui.control.Label
        EditField_64       matlab.ui.control.NumericEditField
        EditField_64Label  matlab.ui.control.Label
        EditField_63       matlab.ui.control.NumericEditField
        EditField_63Label  matlab.ui.control.Label
        EditField_62       matlab.ui.control.NumericEditField
        EditField_62Label  matlab.ui.control.Label
        EditField_61       matlab.ui.control.NumericEditField
        EditField_61Label  matlab.ui.control.Label
        EditField_60       matlab.ui.control.NumericEditField
        EditField_60Label  matlab.ui.control.Label
        EditField_59       matlab.ui.control.NumericEditField
        Label_48           matlab.ui.control.Label
        UIAxes2            matlab.ui.control.UIAxes
        Tab_6              matlab.ui.container.Tab
        Tab_2              matlab.ui.container.Tab
        Tab_3              matlab.ui.container.Tab
        Button_2           matlab.ui.control.Button
        Panel_9            matlab.ui.container.Panel
        GridLayout7        matlab.ui.container.GridLayout
        EditField_36       matlab.ui.control.NumericEditField
        Label_20           matlab.ui.control.Label
        EditField_35       matlab.ui.control.NumericEditField
        Label_19           matlab.ui.control.Label
        EditField_34       matlab.ui.control.NumericEditField
        Label_18           matlab.ui.control.Label
        Panel_8            matlab.ui.container.Panel
        GridLayout6        matlab.ui.container.GridLayout
        EditField_47       matlab.ui.control.NumericEditField
        Label_16           matlab.ui.control.Label
        EditField_48       matlab.ui.control.NumericEditField
        Label_17           matlab.ui.control.Label
        Panel_7            matlab.ui.container.Panel
        GridLayout5        matlab.ui.container.GridLayout
        EditField_44       matlab.ui.control.NumericEditField
        Label_13           matlab.ui.control.Label
        EditField_46       matlab.ui.control.NumericEditField
        Label_15           matlab.ui.control.Label
        EditField_45       matlab.ui.control.NumericEditField
        Label_14           matlab.ui.control.Label
        Tab_9              matlab.ui.container.Tab
        TabGroup4          matlab.ui.container.TabGroup
        Tab_10             matlab.ui.container.Tab
        UIAxes3            matlab.ui.control.UIAxes
        Tab_11             matlab.ui.container.Tab
        UIAxes3_2          matlab.ui.control.UIAxes
        ContextMenu2       matlab.ui.container.ContextMenu
        Menu_10            matlab.ui.container.Menu
    end


    methods (Access = private)

        % 对轴向视图中的绕组端部进行左倒角处理
        function x=x_left_daojiao(~,X,r)
            x1=X(1); x2=X(3);
            arc_1=x1+r+r*cos(linspace(3*pi/2,pi,100));
            arc_2=x1+r+r*cos(linspace(pi,pi/2,100));
            x=[arc_1,arc_2,x2,x2];
        end

        function y=y_left_daojiao(~,Y,r)
            y1=Y(1); y2=Y(2);
            arc_1=y1+r+r*sin(linspace(3*pi/2,pi,100));
            arc_2=y2-r+r*sin(linspace(pi,pi/2,100));
            y=[arc_1,arc_2,y2,y1];
        end

        % 对轴向视图中的绕组端部进行右倒角处理
        function x=x_right_daojiao(~,X,r)
            x1=X(1); x2=X(3);
            arc_1=x2-r+r*cos(linspace(pi/2,0,100));
            arc_2=x2-r+r*cos(linspace(0,-pi/2,100));
            x=[x1,x1,arc_1,arc_2];
        end

        function y=y_right_daojiao(~,Y,r)
            y1=Y(1); y2=Y(2);
            arc_1=y2-r+r*sin(linspace(pi/2,0,100));
            arc_2=y1+r+r*sin(linspace(0,-pi/2,100));
            y=[y1,y2,arc_1,arc_2];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_draw
        function Button_drawPushed(app, event)
            cla(app.UIAxes);

            %% 定子参数
            Ns=app.EditField_1.Value;                          % 槽数
            Rso=app.EditField_2.Value/2;                       % 定子铁心直径
            Rs=app.EditField_3.Value/2;                        % 定子内径
            bsa=app.EditField_4.Value;                         % 齿宽
            slot_depth=app.EditField_5.Value;                  % 槽深
            slot_radius=app.EditField_6.Value;                 % 槽圆角半径
            slot_shoulder_height=app.EditField_7.Value;        % 定子槽肩高
            boa=app.EditField_8.Value;                         % 槽开口
            slot_shoulder_angle=app.EditField_9.Value*pi/180;  % 定子槽肩角
            stator_bushing_thickness=app.EditField_10.Value;   % 定子衬套厚度

            %% 转子参数
            p=app.EditField_11.Value/2;                         % 极对数
            PM_thickness=app.EditField_12.Value;                % 永磁体厚度
            PM_thickness_reduction=app.EditField_13.Value;      % 永磁体边缘厚度削减
            PM_arc_length=app.EditField_14.Value;               % 永磁体弧长[ED]
            PM_segments_circumferential=app.EditField_15.Value; % 永磁体周向分段数
            air_gap=app.EditField_16.Value;                     % 气隙
            rotor_bushing_thickness=app.EditField_17.Value;     % 转子衬套厚度
            spindle_diameter=app.EditField_18.Value;            % 转轴直径
            bore_diameter=app.EditField_19.Value;               % 轴孔直径

            %% 输入参数 → 计算参数
            boa=boa/Rs;                                    % 长度到弧度的转化,原先的boa是槽开口长度，转化后的boa是槽开口弧度
            bt=(2*pi-Ns*boa)/Ns;                           % 根据槽开口弧度求定子内侧齿对应的弧度
            Rt=Rs+slot_shoulder_height;                    % 定子槽顶部半径
            Rsb=Rt+slot_depth;                             % 定子槽底部半径
            theta_yuanjiao=slot_radius/(Rsb-slot_radius);  % 槽圆角半径的弧度

            theta_s=2*pi/Ns;                               % 定子齿槽极距，两个齿槽之间的间距，与选取半径无关，有槽数有关
            bsa=theta_s-bsa*2/(Rt+Rsb);                    % 槽宽角，等于 齿槽极距-齿宽弧度角，齿宽对应半径取槽顶部半径与槽底部半径的平均值

            theta_shoulder=(bsa-boa)/2;                                     % 槽肩角弧度
            height_shoulder=tan(slot_shoulder_angle)*(Rt*theta_shoulder);   % 槽肩高度

            Rm=Rs-air_gap;               % 磁极半径等于定子内半径-气隙长度
            Rr=Rm-PM_thickness;          % 转子外半径等与磁极半径-永磁体厚度
            Rsi=spindle_diameter/2;      % 转子轴承半径等于转轴直径的一半
            Rbore=bore_diameter/2;       % 轴孔半径等于轴孔直径的一半
            %             Rave=(Rm+Rr)/2;              % 永磁体平均半径=(永磁体半径+转子半径)/2
            theta_PM=PM_arc_length*pi/180; % 永磁体弧度=永磁体弧长/永磁体平均半径
            gap_PM=pi/p;                 % 永磁体相隔弧长=pi/p
            theta0=gap_PM/2;             % 第一个永磁体边缘的角度，设为永磁体相隔弧长的一半


            %% 画全部定子槽形
            for i = 1:Ns
                ty=(i-1)*theta_s;
                tooth1=linspace(0+ty,(theta_s-boa)/2+ty,100);                        % 右齿部的弧度范围
                tooth2=linspace((theta_s+boa)/2+ty,theta_s+ty,100);                  % 左齿部的弧度范围
                slot_shoulder1=linspace((theta_s-boa)/2+ty,(theta_s-bsa)/2+ty,100);  % 右槽肩角的弧度范围
                slot_shoulder2=linspace((theta_s+bsa)/2+ty,(theta_s+boa)/2+ty,100);  % 左槽肩角的弧度范围

                arc_1=(theta_s-bsa)/2+ty+theta_yuanjiao;     % 槽圆角1的弧度
                arc_2=(theta_s+bsa)/2+ty-theta_yuanjiao;     % 槽圆角2的弧度
                arc_1_range=linspace(arc_1-pi/2,arc_1,100);
                arc_2_range=linspace(arc_2,arc_2+pi/2,100);
                x_arc_1=(Rsb-slot_radius)*cos(arc_1)+slot_radius*cos(arc_1_range);
                y_arc_1=(Rsb-slot_radius)*sin(arc_1)+slot_radius*sin(arc_1_range);
                x_arc_2=(Rsb-slot_radius)*cos(arc_2)+slot_radius*cos(arc_2_range);
                y_arc_2=(Rsb-slot_radius)*sin(arc_2)+slot_radius*sin(arc_2_range);
                x_arc_3=Rsb*cos(linspace(arc_1,arc_2,30));
                y_arc_3=Rsb*sin(linspace(arc_1,arc_2,30));

                slot_range=linspace(theta_s+ty,0+ty,100);

                x_tooth1=Rs*cos(tooth1);
                y_tooth1=Rs*sin(tooth1);
                x_tooth2=Rs*cos(tooth2);
                y_tooth2=Rs*sin(tooth2);

                if slot_shoulder_angle == 0
                    x_shoulder1=Rt*cos(slot_shoulder1);
                    y_shoulder1=Rt*sin(slot_shoulder1);
                    x_shoulder2=Rt*cos(slot_shoulder2);
                    y_shoulder2=Rt*sin(slot_shoulder2);

                else
                    x_shoulder1=[Rt*cos((theta_s-boa)/2+ty),(Rt+height_shoulder)*cos((theta_s-bsa)/2+ty)];
                    y_shoulder1=[Rt*sin((theta_s-boa)/2+ty),(Rt+height_shoulder)*sin((theta_s-bsa)/2+ty)];
                    x_shoulder2=[(Rt+height_shoulder)*cos((theta_s+bsa)/2+ty),Rt*cos((theta_s+boa)/2+ty)];
                    y_shoulder2=[(Rt+height_shoulder)*sin((theta_s+bsa)/2+ty),Rt*sin((theta_s+boa)/2+ty)];
                end

                x_slot=Rso*cos(slot_range);
                y_slot=Rso*sin(slot_range);

                x_begin=Rs*cos(0+ty);
                y_begin=Rs*sin(0+ty);

                X1=[x_tooth1, x_shoulder1, x_arc_1,x_arc_2, x_shoulder2, x_tooth2];
                Y1=[y_tooth1, y_shoulder1, y_arc_1,y_arc_2, y_shoulder2, y_tooth2];
                plot(app.UIAxes,X1,Y1,'-k',x_slot,y_slot,'-k');
                hold(app.UIAxes,'on');
                plot(app.UIAxes,Rs*cos(linspace((theta_s-boa)/2+ty,(theta_s+boa)/2+ty,50)),Rs*sin(linspace((theta_s-boa)/2+ty,(theta_s+boa)/2+ty,50)),'k:')
                hold(app.UIAxes,'on');
                plot(app.UIAxes,Rt*cos(linspace((theta_s-boa)/2+ty,(theta_s+boa)/2+ty,50)),Rt*sin(linspace((theta_s-boa)/2+ty,(theta_s+boa)/2+ty,50)),'k:')
                hold(app.UIAxes,'on');
                if app.EditField_70.Value == 0
                    switch app.DropDown.Value
                        case "平行齿"
                            X=[x_tooth1, x_shoulder1, x_arc_1, x_arc_2, x_shoulder2, x_tooth2, x_slot, x_begin];      % 定子槽形的轮廓，X收集x坐标
                            Y=[y_tooth1, y_shoulder1, y_arc_1, y_arc_2, y_shoulder2, y_tooth2, y_slot, y_begin];      % 定子槽形的轮廓，Y收集y坐标
                            fill(app.UIAxes,X,Y,[1, 0, 0],EdgeColor='none')                                           % 给定子槽涂上红色
                            if slot_shoulder_angle == 0
                            else
                                fill(app.UIAxes, [x_shoulder1,x_shoulder2], [y_shoulder1,y_shoulder2],[0.80,0.78,0.78])   % 给定子槽肩部涂上灰色
                            end
                            % 给定子槽内涂上黄色
                            fill(app.UIAxes,[(Rt+height_shoulder)*cos((theta_s-bsa)/2+ty),x_arc_1,x_arc_2,(Rt+height_shoulder)*cos((theta_s+bsa)/2+ty)],[(Rt+height_shoulder)*sin((theta_s-bsa)/2+ty),y_arc_1,y_arc_2,(Rt+height_shoulder)*sin((theta_s+bsa)/2+ty)],'y')
                            hold(app.UIAxes,'on');
                            axis(app.UIAxes,'off');
                        case "平行齿平底槽"
                            X=[x_tooth1, x_shoulder1, x_arc_1, x_arc_3, x_arc_2, x_shoulder2, x_tooth2, x_slot, x_begin];      % 定子槽形的轮廓，X收集x坐标
                            Y=[y_tooth1, y_shoulder1, y_arc_1, y_arc_3, y_arc_2, y_shoulder2, y_tooth2, y_slot, y_begin];      % 定子槽形的轮廓，Y收集y坐标
                            fill(app.UIAxes,X,Y,[1, 0, 0],EdgeColor='none')                                           % 给定子槽涂上红色
                            if slot_shoulder_angle == 0
                            else
                                fill(app.UIAxes, [x_shoulder1,x_shoulder2], [y_shoulder1,y_shoulder2],[0.80,0.78,0.78])   % 给定子槽肩部涂上灰色
                            end
                            % 给定子槽内涂上黄色
                            if slot_shoulder_angle == 0
                                fill(app.UIAxes,[Rt*cos(linspace((theta_s+bsa)/2+ty,(theta_s-bsa)/2+ty,50)),x_arc_1,x_arc_3,x_arc_2],[Rt*sin(linspace((theta_s+bsa)/2+ty,(theta_s-bsa)/2+ty,50)),y_arc_1,y_arc_3,y_arc_2],'y')
                            else
                                fill(app.UIAxes,[(Rt+height_shoulder)*cos((theta_s-bsa)/2+ty),x_arc_1,x_arc_3,x_arc_2,(Rt+height_shoulder)*cos((theta_s+bsa)/2+ty)],[(Rt+height_shoulder)*sin((theta_s-bsa)/2+ty),y_arc_1,y_arc_3,y_arc_2,(Rt+height_shoulder)*sin((theta_s+bsa)/2+ty)],'y')
                            end
                            hold(app.UIAxes,'on');
                            axis(app.UIAxes,'off');
                    end
                else

                    % 绘制辅助槽
                    Na=app.EditField_70.Value;   % 辅助槽的个数
                    wa=app.EditField_71.Value;   % 辅助槽的宽度
                    Da=app.EditField_72.Value;   % 辅助槽的深度

                    delta=zeros(2*Na,1);         % 记录辅助槽直角点的弧度
                    Lt=theta_s-boa;              % 一个定子齿的弧度大小

                    for ia = 1:Na
                        k=2*ia-1;
                        delta_L=Lt/(Na+1);
                        delta(k+1,1)=Lt/2-(ia*delta_L+wa/2);  % 槽开口右直角的弧度
                        delta(k,1)=Lt/2-(ia*delta_L-wa/2);    % 槽开口左直角的弧度
                        if delta(k,1) < 0
                            delta(k,1)=delta(k,1)+theta_s;
                        end
                        if delta(k+1,1) < 0
                            delta(k+1,1)=delta(k+1,1)+theta_s;
                        end
                    end
                    delta=sort(delta)+ty;

                    if mod(Na,2)==1
                        % 辅助槽的个数为奇数时

                        %先处理右边的辅助槽
                        ta1=linspace(0+ty,delta(1,1),10);
                        x_ta1=(Rs+Da)*cos(ta1);
                        y_ta1=(Rs+Da)*sin(ta1);
                        for ia = 1:Na-1
                            ta=linspace(delta(ia,1),delta(ia+1),10);
                            if mod(ia,2)==1
                                x_ta=Rs*cos(ta);
                                y_ta=Rs*sin(ta);
                            else
                                x_ta=(Rs+Da)*cos(ta);
                                y_ta=(Rs+Da)*sin(ta);
                            end
                            x_ta1=[x_ta1 x_ta];
                            y_ta1=[y_ta1 y_ta];
                        end
                        x_ta1=[x_ta1 Rs*cos(linspace(delta(Na),(theta_s-boa)/2+ty,10))];
                        y_ta1=[y_ta1 Rs*sin(linspace(delta(Na),(theta_s-boa)/2+ty,10))];

                        % 再处理左边的辅助槽
                        ta2=linspace((theta_s+boa)/2+ty,delta(Na+1,1),10);
                        x_ta2=Rs*cos(ta2);
                        y_ta2=Rs*sin(ta2);
                        for ia = 1:Na-1
                            ta=linspace(delta(ia+Na,1),delta(ia+1+Na,1),10);
                            if mod(ia+Na,2)==1
                                x_ta=Rs*cos(ta);
                                y_ta=Rs*sin(ta);
                            else
                                x_ta=(Rs+Da)*cos(ta);
                                y_ta=(Rs+Da)*sin(ta);
                            end
                            x_ta2=[x_ta2 x_ta];
                            y_ta2=[y_ta2 y_ta];
                        end
                        x_ta2=[x_ta2 (Rs+Da)*cos(linspace(delta(2*Na),theta_s+ty,10))];
                        y_ta2=[y_ta2 (Rs+Da)*sin(linspace(delta(2*Na),theta_s+ty,10))];

                    else
                        % 辅助槽的个数为偶数

                        %先处理右边的辅助槽
                        ta1=linspace(0+ty,delta(1,1),10);
                        x_ta1=Rs*cos(ta1);
                        y_ta1=Rs*sin(ta1);
                        for ia = 1:Na-1
                            ta=linspace(delta(ia,1),delta(ia+1),10);
                            if mod(ia,2)==0
                                x_ta=Rs*cos(ta);
                                y_ta=Rs*sin(ta);
                            else
                                x_ta=(Rs+Da)*cos(ta);
                                y_ta=(Rs+Da)*sin(ta);
                            end
                            x_ta1=[x_ta1 x_ta];
                            y_ta1=[y_ta1 y_ta];
                        end
                        x_ta1=[x_ta1 Rs*cos(linspace(delta(Na),(theta_s-boa)/2+ty,10))];
                        y_ta1=[y_ta1 Rs*sin(linspace(delta(Na),(theta_s-boa)/2+ty,10))];

                        % 再处理左边的辅助槽
                        ta2=linspace((theta_s+boa)/2+ty,delta(Na+1,1),10);
                        x_ta2=Rs*cos(ta2);
                        y_ta2=Rs*sin(ta2);
                        for ia = 1:Na-1
                            ta=linspace(delta(ia+Na,1),delta(ia+1+Na,1),10);
                            if mod(ia+Na,2)==0
                                x_ta=Rs*cos(ta);
                                y_ta=Rs*sin(ta);
                            else
                                x_ta=(Rs+Da)*cos(ta);
                                y_ta=(Rs+Da)*sin(ta);
                            end
                            x_ta2=[x_ta2 x_ta];
                            y_ta2=[y_ta2 y_ta];
                        end
                        x_ta2=[x_ta2 (Rs+Da)*cos(linspace(delta(2*Na),theta_s+ty,10))];
                        y_ta2=[y_ta2 (Rs+Da)*sin(linspace(delta(2*Na),theta_s+ty,10))];
                    end
                    switch app.DropDown.Value
                        case "平行齿"
                            X=[x_ta1, x_shoulder1, x_arc_1, x_arc_2, x_shoulder2, x_ta2, x_slot, x_begin];      % 定子槽形的轮廓，X收集x坐标
                            Y=[y_ta1, y_shoulder1, y_arc_1, y_arc_2, y_shoulder2, y_ta2, y_slot, y_begin];      % 定子槽形的轮廓，Y收集y坐标
                            fill(app.UIAxes,X,Y,[1, 0, 0],EdgeColor='none')                                           % 给定子槽涂上红色
                            if slot_shoulder_angle == 0
                            else
                                fill(app.UIAxes, [x_shoulder1,x_shoulder2], [y_shoulder1,y_shoulder2],[0.80,0.78,0.78])   % 给定子槽肩部涂上灰色
                            end
                            % 给定子槽内涂上黄色
                            fill(app.UIAxes,[(Rt+height_shoulder)*cos((theta_s-bsa)/2+ty),x_arc_1,x_arc_2,(Rt+height_shoulder)*cos((theta_s+bsa)/2+ty)],[(Rt+height_shoulder)*sin((theta_s-bsa)/2+ty),y_arc_1,y_arc_2,(Rt+height_shoulder)*sin((theta_s+bsa)/2+ty)],'y')
                            hold(app.UIAxes,'on');
                            axis(app.UIAxes,'off');
                        case "平行齿平底槽"
                            X=[x_ta1, x_shoulder1, x_arc_1, x_arc_3, x_arc_2, x_shoulder2, x_ta2, x_slot, x_begin];      % 定子槽形的轮廓，X收集x坐标
                            Y=[y_ta1, y_shoulder1, y_arc_1, y_arc_3, y_arc_2, y_shoulder2, y_ta2, y_slot, y_begin];      % 定子槽形的轮廓，Y收集y坐标
                            fill(app.UIAxes,X,Y,[1, 0, 0],EdgeColor='none')                                           % 给定子槽涂上红色
                            if slot_shoulder_angle == 0
                            else
                                fill(app.UIAxes, [x_shoulder1,x_shoulder2], [y_shoulder1,y_shoulder2],[0.80,0.78,0.78])   % 给定子槽肩部涂上灰色
                            end
                            % 给定子槽内涂上黄色
                            if slot_shoulder_angle == 0
                                fill(app.UIAxes,[Rt*cos(linspace((theta_s+bsa)/2+ty,(theta_s-bsa)/2+ty,50)),x_arc_1,x_arc_3,x_arc_2],[Rt*sin(linspace((theta_s+bsa)/2+ty,(theta_s-bsa)/2+ty,50)),y_arc_1,y_arc_3,y_arc_2],'y')
                            else
                                fill(app.UIAxes,[(Rt+height_shoulder)*cos((theta_s-bsa)/2+ty),x_arc_1,x_arc_3,x_arc_2,(Rt+height_shoulder)*cos((theta_s+bsa)/2+ty)],[(Rt+height_shoulder)*sin((theta_s-bsa)/2+ty),y_arc_1,y_arc_3,y_arc_2,(Rt+height_shoulder)*sin((theta_s+bsa)/2+ty)],'y')
                            end
                            hold(app.UIAxes,'on');
                            axis(app.UIAxes,'off');
                    end


                end
            end
            %% 画转子及转轴

            R_stator_bushing=Rs-stator_bushing_thickness;  % 定子衬套内径
            R_rotor_bushing=Rm+rotor_bushing_thickness;    % 转子衬套外径
            th=linspace(0,2*pi,1000);

            % 定子内径
            x_stator=Rs*cos(th);
            y_stator=Rs*sin(th);

            % 定子衬套内径
            x_stator_bushing=R_stator_bushing*cos(th);
            y_stator_bushing=R_stator_bushing*sin(th);

            % 转子衬套外径
            x_rotor_bushing=R_rotor_bushing*cos(th);
            y_rotor_bushing=R_rotor_bushing*sin(th);

            % 永磁体外径
            x_PM=Rm*cos(th);
            y_PM=Rm*sin(th);

            % 转子外径
            x_rotor=Rr*cos(th);
            y_rotor=Rr*sin(th);

            % 转子轴承半径
            x_si=Rsi*cos(th);
            y_si=Rsi*sin(th);

            % 勾勒定子衬套轮廓，定子衬套轮廓外径为Rs，内径为R_stator_bushing
            x1=[x_stator,x_stator_bushing,Rs*cos(0)];
            y1=[y_stator,y_stator_bushing,Rs*sin(0)];

            % 勾勒转子衬套轮廓，转子衬套轮廓外径为R_rotor_bushing，内径为Rm
            x2=[x_rotor_bushing,x_PM,Rm*cos(0)];
            y2=[y_rotor_bushing,y_PM,Rm*sin(0)];

            % 绘制永磁体，永磁体外径为Rm，内径为Rr
            for i = 1:2*p
                theta_begin=theta0+gap_PM*(i-1);
                theta_segments=theta_PM/PM_segments_circumferential;

                omega=pi/theta_PM-2*asin(1-PM_thickness_reduction/PM_thickness)/theta_PM;
                fai=asin(1-PM_thickness_reduction/PM_thickness)-omega*theta_begin;

                for j = 1:PM_segments_circumferential

                    p1=theta_begin+theta_segments*(j-1);
                    p2=p1+theta_segments;

                    % 永磁体边缘削减，永磁体的变化轮廓为弧形
                    R_PM=PM_thickness*sin(omega*linspace(p2,p1,50)+fai)+Rr;
                    R_PM_diag=diag(R_PM);

                    arc_x=[Rr*cos(linspace(p1,p2,50)),cos(linspace(p2,p1,50))*R_PM_diag];
                    arc_y=[Rr*sin(linspace(p1,p2,50)),sin(linspace(p2,p1,50))*R_PM_diag];

                    fill(app.UIAxes,arc_x,arc_y,[0.13, 0.55, 0.13],EdgeColor='k')
                    hold(app.UIAxes,'on');
                end
            end

            % 勾勒转子轮廓，转子外径为Rr，内径为Rsi
            x4=[x_rotor, x_si,Rr*cos(0)];
            y4=[y_rotor, y_si,Rr*sin(0)];

            plot(app.UIAxes,x_rotor,y_rotor,'-k');
            hold(app.UIAxes,'on');

            plot(app.UIAxes,x_si,y_si,'-k');
            hold(app.UIAxes,'on');

            % 为定子衬套上色
            if stator_bushing_thickness == 0
            else
                fill(app.UIAxes,x1, y1,[0, 1, 1],EdgeColor='none');
                hold(app.UIAxes,'on');
            end

            % 为转子衬套上色
            if rotor_bushing_thickness == 0
            else
                fill(app.UIAxes,x2, y2,[0.635, 0.078, 0.184],EdgeColor='none');
                hold(app.UIAxes,'on');
            end

            % 为转子铁心上色
            fill(app.UIAxes,x4, y4,[0, 1, 1],EdgeColor='none');
            hold(app.UIAxes,'on');

            % 为转子转轴上色
            fill(app.UIAxes,x_si, y_si,[0.7451, 0.7451, 0.7451],EdgeColor='k');
            hold(app.UIAxes,'on');

            % 为转子轴孔上色
            fill(app.UIAxes,Rbore*cos(linspace(0,2*pi,100)), Rbore*sin(linspace(0,2*pi,100)),'w',EdgeColor='k');
            hold(app.UIAxes,'on');

        end

        % Callback function
        function Menu_9Selected(app, event)
            % 将电机径向参数填入轴线参数界面表中

            app.EditField_49.Value = app.EditField_2.Value;
            app.EditField_50.Value = app.EditField_3.Value;
            app.EditField_51.Value = app.EditField_16.Value;
            app.EditField_52.Value = app.EditField_17.Value;
            app.EditField_53.Value = app.EditField_10.Value;
            app.EditField_54.Value = app.EditField_12.Value;
            app.EditField_55.Value = app.EditField_18.Value;
            app.EditField_58.Value = app.EditField_19.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cla(app.UIAxes2);

            %% 径向尺寸参数
            Rso=app.EditField_49.Value/2;                      % 定子铁心直径
            Rs=app.EditField_50.Value/2;                       % 定子内径
            air_gap=app.EditField_51.Value;                    % 气隙
            rotor_bushing_thickness=app.EditField_52.Value;    % 转子衬套厚度
            stator_bushing_thickness=app.EditField_53.Value;   % 定子衬套厚度
            PM_thickness=app.EditField_54.Value;               % 永磁体厚度
            spindle_diameter=app.EditField_55.Value;           % 转轴直径
            forward_spindle_diameter=app.EditField_56.Value;   % 前转轴直径
            back_spindle_diameter=app.EditField_57.Value;      % 后转轴直径
            bore_diameter=app.EditField_58.Value;              % 轴孔直径

            %% 轴向尺寸参数
            motor_length=app.EditField_59.Value;                      % 电机长度
            stator_length=app.EditField_60.Value;                     % 定子铁心长
            PM_length=app.EditField_61.Value;                         % 永磁体长度
            PM_segments_axial=app.EditField_62.Value;                 % 永磁体轴向分段数
            rotor_length=app.EditField_63.Value;                      % 转子铁心长
            end_wingding_overhang_forward=app.EditField_64.Value;     % 端部绕组悬垂[前部]
            end_wingding_overhang_back=app.EditField_65.Value;        % 端部绕组悬垂[后部]
            end_wingding_straightness_forward=app.EditField_66.Value; % 端部绕组直线长[前]
            end_wingding_straightness_back=app.EditField_67.Value;    % 端部绕组直线长[后]
            spindle_extension_forward=app.EditField_68.Value;         % 转轴伸出长[前]
            spindle_extension_back=app.EditField_69.Value;            % 转轴伸出长[后]
            r_windings=app.Slider.Value;                              % 绕组倒角半径

            %% 输入参数 → 计算参数
            Rsi=spindle_diameter/2;                                   % 转轴半径=转轴直径/2
            Rm=Rs-air_gap;                                            % 永磁体半径=定子内径-气隙长度
            Rr=Rm-PM_thickness;                                       % 转子铁心半径=永磁体半径-永磁体厚度
            Rt=Rs+app.EditField_7.Value;                              % 定子槽顶部半径=定子内半径-定子槽肩高
            Rsb=Rt+app.EditField_5.Value;                            % 定子槽底部半径=定子槽顶部半径+槽深
            Rbore=bore_diameter/2;                                    % 轴孔半径=轴孔直径/2

            % 转轴长度=电机长度+转轴伸出长[前]+转轴伸出长[后]
            x_spindle_middle=[-motor_length/2,-motor_length/2,motor_length/2,motor_length/2];
            x_spindle_forward=[-motor_length/2-spindle_extension_forward,-motor_length/2-spindle_extension_forward,-motor_length/2,-motor_length/2];
            x_spindle_back=[motor_length/2,motor_length/2,motor_length/2+spindle_extension_back,motor_length/2+spindle_extension_back];

            y_spindle_middle=[Rbore,Rsi,Rsi,Rbore];
            y_spindle_forward=[Rbore,forward_spindle_diameter/2,forward_spindle_diameter/2,Rbore];
            y_spindle_back=[Rbore,back_spindle_diameter/2,back_spindle_diameter/2,Rbore];

            % 转轴轴孔
            x_bore=[-motor_length/2-spindle_extension_forward,-motor_length/2-spindle_extension_forward,motor_length/2+spindle_extension_back,motor_length/2+spindle_extension_back];
            y_bore=[-Rbore,Rbore,Rbore,-Rbore];

            % 端部绕组悬垂[前部]与[后部]
            x_winding_forward=[-stator_length/2-end_wingding_overhang_forward,-stator_length/2-end_wingding_overhang_forward,-stator_length/2,-stator_length/2];
            x_winding_back=[stator_length/2, stator_length/2,stator_length/2+end_wingding_overhang_back, stator_length/2+end_wingding_overhang_back];
            x_winding_forward=x_left_daojiao(app,x_winding_forward,r_windings);
            x_winding_back=x_right_daojiao(app,x_winding_back,r_windings);

            y_winding=[Rt,Rsb,Rsb,Rt];  % 端部绕组悬垂的前部和后部共用一套纵坐标
            y_winding_forward=y_left_daojiao(app,y_winding,r_windings);
            y_winding_back=y_right_daojiao(app,y_winding,r_windings);

            % 转子衬套
            x_rotor_bushing=[-rotor_length/2,-rotor_length/2,rotor_length/2,rotor_length/2];
            y_rotor_bushing=[Rm,Rm+rotor_bushing_thickness,Rm+rotor_bushing_thickness,Rm];

            % 转子铁心
            x_rotor=[-rotor_length/2,-rotor_length/2,rotor_length/2,rotor_length/2];
            y_rotor=[Rsi,Rr,Rr,Rsi];

            % 永磁体PM，并以分段的形式将其画出来
            y_PM=[Rr,Rm,Rm,Rr];
            for i = 1:PM_segments_axial
                PM_sements_length=PM_length/PM_segments_axial;

                x_PM=[-PM_length/2+(i-1)*PM_sements_length,-PM_length/2+(i-1)*PM_sements_length,-PM_length/2+i*PM_sements_length,-PM_length/2+i*PM_sements_length];
                fill(app.UIAxes2,x_PM,y_PM,[0, 1, 0],EdgeColor='k')
                hold(app.UIAxes2,'on');
                fill(app.UIAxes2,x_PM,-y_PM,[0, 1, 0],EdgeColor='k')
                hold(app.UIAxes2,'on');
            end

            % 定子衬套
            x_stator_bushing=[-stator_length/2,-stator_length/2,stator_length/2,stator_length/2];
            y_stator_bushing=[Rs-stator_bushing_thickness,Rs,Rs,Rs-stator_bushing_thickness];

            % 定子铁心
            x_stator=[-stator_length/2,-stator_length/2,stator_length/2,stator_length/2];
            y_stator=[Rs,Rso,Rso,Rs];
            %% 画图工作

            % 画转轴轴孔
            fill(app.UIAxes2,x_bore,y_bore,[1,1,1],EdgeColor='w')
            hold(app.UIAxes2,'on');

            % 画转轴截面
            fill(app.UIAxes2,x_spindle_middle,y_spindle_middle,[0.7451, 0.7451, 0.7451],'EdgeColor','k');  % 画转轴中部
            fill(app.UIAxes2,x_spindle_forward,y_spindle_forward,[0.7451, 0.7451, 0.7451],'EdgeColor','k');% 画转轴前部
            fill(app.UIAxes2,x_spindle_back,y_spindle_back,[0.7451, 0.7451, 0.7451],'EdgeColor','k');      % 画转轴后部
            hold(app.UIAxes2,'on');

            fill(app.UIAxes2,x_spindle_middle,-y_spindle_middle,[0.7451, 0.7451, 0.7451],'EdgeColor','k');  % 画转轴中部
            fill(app.UIAxes2,x_spindle_forward,-y_spindle_forward,[0.7451, 0.7451, 0.7451],'EdgeColor','k');% 画转轴前部
            fill(app.UIAxes2,x_spindle_back,-y_spindle_back,[0.7451, 0.7451, 0.7451],'EdgeColor','k');      % 画转轴后部
            hold(app.UIAxes2,'on');

            % 画转子衬套
            fill(app.UIAxes2,x_rotor_bushing, y_rotor_bushing,[0.635, 0.078, 0.184],EdgeColor='k');
            fill(app.UIAxes2,x_rotor_bushing, -y_rotor_bushing,[0.635, 0.078, 0.184],EdgeColor='k');
            hold(app.UIAxes2,'on');

            % 画转子截面
            fill(app.UIAxes2,x_rotor, y_rotor,[0, 1, 1],EdgeColor='k');
            fill(app.UIAxes2,x_rotor, -y_rotor,[0, 1, 1],EdgeColor='k');
            hold(app.UIAxes2,'on');

            % 画定子衬套
            fill(app.UIAxes2,x_stator_bushing, y_stator_bushing,[0, 1, 1],EdgeColor='k');
            fill(app.UIAxes2,x_stator_bushing, -y_stator_bushing,[0, 1, 1],EdgeColor='k');
            hold(app.UIAxes2,'on');

            % 画定子截面
            fill(app.UIAxes2,x_stator, y_stator,[1, 0, 0],EdgeColor='k');
            fill(app.UIAxes2,x_stator, -y_stator,[1, 0, 0],EdgeColor='k');
            hold(app.UIAxes2,'on');

            % 画端部绕组悬垂[前部]与[后部]
            fill(app.UIAxes2,x_winding_forward, y_winding_forward,[1, 1, 0],EdgeColor='k');
            fill(app.UIAxes2,x_winding_forward, -y_winding_forward,[1, 1, 0],EdgeColor='k');
            fill(app.UIAxes2,x_winding_back, y_winding_back,[1, 1, 0],EdgeColor='k');
            fill(app.UIAxes2,x_winding_back, -y_winding_back,[1, 1, 0],EdgeColor='k');
            hold(app.UIAxes2,'on');

            % 画横纵虚线
            % 画纵虚线
            plot(app.UIAxes2,[motor_length/2+end_wingding_straightness_back,motor_length/2+end_wingding_straightness_back],[Rso,-Rso],'-.k')
            plot(app.UIAxes2,[-motor_length/2-end_wingding_straightness_forward,-motor_length/2-end_wingding_straightness_forward],[Rso,-Rso],'-.k')
            % 画横虚线
            plot(app.UIAxes2,[-motor_length/2-end_wingding_straightness_forward,motor_length/2+end_wingding_straightness_back],[0,0],'-.k')
            % 画横实线
            plot(app.UIAxes2,[motor_length/2+end_wingding_straightness_back-2,motor_length/2+end_wingding_straightness_back+2],[Rso,Rso],'-k')
            plot(app.UIAxes2,[motor_length/2+end_wingding_straightness_back-2,motor_length/2+end_wingding_straightness_back+2],[-Rso,-Rso],'-k')
            plot(app.UIAxes2,[-motor_length/2-end_wingding_straightness_forward-2,-motor_length/2-end_wingding_straightness_forward+2],[Rso,Rso],'-k')
            plot(app.UIAxes2,[-motor_length/2-end_wingding_straightness_forward-2,-motor_length/2-end_wingding_straightness_forward+2],[-Rso,-Rso],'-k')
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            cla(app.UIAxes3);
            msgbox("正在计算中...","提示框","help")
            %% 定子参数
            Ns=app.EditField_1.Value;                               % 槽数
            Rs=app.EditField_3.Value/2000;                          % 定子内径
            bsa=app.EditField_4.Value/1000;                         % 齿宽
            slot_depth=app.EditField_5.Value/1000;                  % 槽深
            slot_shoulder_height=app.EditField_7.Value/1000;        % 定子槽肩高
            boa=app.EditField_8.Value/1000;                         % 槽开口

            %% 转子参数
            p=app.EditField_11.Value/2;                              % 极对数
            PM_thickness=app.EditField_12.Value/1000;                % 永磁体厚度
            PM_arc_length=app.EditField_14.Value/1000;               % 永磁体弧长[ED]
            air_gap=app.EditField_16.Value/1000;                     % 气隙

            %% 输入参数 → 计算参数
            boa=boa/Rs;                                    % 长度到弧度的转化,原先的boa是槽开口长度，转化后的boa是槽开口角度
            Rt=Rs+slot_shoulder_height;                    % 定子槽顶部半径
            Rsb=Rt+slot_depth;                             % 定子槽底部半径
            theta_s=2*pi/Ns;                               % 定子齿槽极距，两个齿槽之间的间距，与选取半径无关，有槽数有关
            bsa=theta_s-bsa*2/(Rt+Rsb);                    % 槽宽角，等于齿槽极距-齿宽弧度角，齿宽对应半径取槽顶部半径与槽底部半径的平均值

            Rm=Rs-air_gap;               % 磁极半径等于定子内半径-气隙长度
            Rr=Rm-PM_thickness;          % 转子外半径等与磁极半径-永磁体厚度
            Rave=(Rm+Rr)/2;              % 永磁体平均半径=(永磁体半径+转子半径)/2
            theta_PM=PM_arc_length/Rave; % 永磁体弧度=永磁体弧长/永磁体平均半径
            gap_PM=pi/p;                 % 永磁体相隔弧长=pi/p
            ap=theta_PM/gap_PM;          % 极弧系数=永磁体弧度/永磁体相隔弧长

            %% 其他参数

            a0=0;                               % 转子初始位置角

            u0=app.EditField_44.Value;          % 真空磁导率
            ur=app.EditField_45.Value;          % PM的相对磁导率，解析模型的假设
            Br=app.EditField_46.Value;          % PM的剩磁/T，有限元中使用钕铁硼材料的剩磁密度
            wr=app.EditField_47.Value*2*pi/60;
            t=app.EditField_48.Value;           % 转子转动的时刻
            % 在这组参数下，最大谐波次数取值。减小Rr,Rm,Rs半径间的差别，影响不大
            N=app.EditField_34.Value;           % 气隙及磁极谐波次
            M=app.EditField_35.Value;           % 齿槽谐波次数取值数取值。在这组参数下，最大谐波次数取值。减小Rr,Rm,Rs半径间的差别，影响不大
            k=app.EditField_36.Value;           % 谐波次数

            %% 选择充磁方式 1.径向充磁 2.平行充磁

            Mrck=zeros(k,1);
            Mrsk=zeros(k,1);
            Mack=zeros(k,1);
            Mask=zeros(k,1);
            magnetization_method=2;

            switch(magnetization_method)
                case 1
                    % radial magnetization 径向磁化
                    for i = 1:k
                        b=2*i-1;
                        if b > k
                            break
                        end
                        Mrk=4*Br/(b/p*pi*u0)*sin(b/p*pi*ap/4)*(sin(b/p*theta_s/2)-sin(b/p*3*theta_s/2));

                        Mak=0;

                        Mrck(b,1)=Mrk*cos(b*wr*t+b*a0);
                        Mrsk(b,1)=Mrk*sin(b*wr*t+b*a0);
                        Mack(b,1)=-Mak*sin(b*wr*t+b*a0);
                        Mask(b,1)=Mak*cos(b*wr*t+b*a0);
                    end

                case 2
                    % parallel magnetization 平行磁化 k=1时A2k的分母为0要注意
                    for b = 1:k

                        A1k=sin((b*p+1)*(pi/2/p)*ap)/((b*p+1)*(pi/2/p)*ap);
                        A2k=sin((b*p-1)*(pi/2/p)*ap)/((b*p-1)*(pi/2/p)*ap);
                        if b == 1
                            A2k=1;
                        end
                        Mrk=(Br/u0)*ap*(A1k+A2k);
                        Mak=(Br/u0)*ap*(A1k-A2k);

                        Mrck(b,1)=Mrk*cos(b*wr*t+b*a0);
                        Mrsk(b,1)=Mrk*sin(b*wr*t+b*a0);
                        Mack(b,1)=-Mak*sin(b*wr*t+b*a0);
                        Mask(b,1)=Mak*cos(b*wr*t+b*a0);
                    end
            end

            %% permanent magnet and air-gap 永磁体与气隙之间
            Ik=eye(k,k);
            K=eye(k,k);
            K_special=eye(k,k);
            for i = 1:k
                if i == 1
                    K_special(i,i)=sqrt(2);
                else
                    K(i,i)=i;
                    K_special(i,i)=i;
                end
            end

            g1=Rr/Rm;
            g2=Rm/Rs;
            G1=eye(k,k);
            G2=eye(k,k);
            for i = 1:k
                G1(i,i)=g1^i;
                G2(i,i)=g2^i;
            end
            K11=Ik+G1^2;
            K22=Ik+G1^2;
            K13=-G2;
            K25=-G2;
            K14=-Ik;
            K26=-Ik;

            K31=Ik-G1^2;
            K42=Ik-G1^2;
            K33=-ur*G2;
            K45=-ur*G2;
            K34=ur*Ik;
            K46=ur*Ik;

            %% 定义Y向量

            Y1=zeros(k,1);
            Y2=zeros(k,1);
            Y3=zeros(k,1);
            Y4=zeros(k,1);

            for i = 1:k
                g1=(Rr/Rm)^i;
                if i == 1
                    Y1(i,1)=-u0*((Rr^2*(1-log(Rr))/(2*Rm)-Rm*log(Rm)/2)*Mack(1,1)+(Rr^2*(1+log(Rr))/(2*Rm)+Rm*log(Rm)/2)*Mrsk(1,1));
                    Y2(i,1)=-u0*((Rr^2*(1-log(Rr))/(2*Rm)-Rm*log(Rm)/2)*Mask(1,1)-(Rr^2*(1+log(Rr))/(2*Rm)+Rm*log(Rm)/2)*Mrck(1,1));
                    Y3(i,1)=-u0*((Rr^2*(log(Rr)-1)/(2*Rm)+Rm*(1-log(Rm))/2)*Mack(1,1)+(Rm*(1+log(Rm))/2-Rr^2*(log(Rr)+1)/(2*Rm))*Mrsk(1,1));
                    Y4(i,1)=-u0*((Rr^2*(log(Rr)-1)/(2*Rm)+Rm*(1-log(Rm))/2)*Mask(1,1)-(Rm*(1+log(Rm))/2-Rr^2*(log(Rr)+1)/(2*Rm))*Mrck(1,1));
                else
                    Y1(i,1)=-u0*((i^2-1)^(-1))*((Rr*i*g1+Rm*1)*Mack(i,1)-(Rr*g1+Rm*i)*Mrsk(i,1));
                    Y2(i,1)=-u0*((i^2-1)^(-1))*((Rr*i*g1+Rm*1)*Mask(i,1)+(Rr*g1+Rm*i)*Mrck(i,1));
                    Y3(i,1)=-u0*((i^2-1)^(-1))*(i*(Rm*1-Rr*g1)*Mack(i,1)-(Rm*1-Rr*g1)*Mrsk(i,1));
                    Y4(i,1)=-u0*((i^2-1)^(-1))*(i*(Rm*1-Rr*g1)*Mask(i,1)+(Rm*1-Rr*g1)*Mrck(i,1));
                end
            end

            %% slot opening and slot 槽开口与槽之间
            Fm=eye(M,M);
            En=eye(N,N);
            G3=eye(N,N);
            G4=eye(M,M);
            IN=eye(N,N);
            gamma=eye(M,N);

            for i = 1:M
                Fm(i,i)=i*pi/boa;
                G4(i,i)=(Rs/Rt)^(i*pi/boa);
            end
            for i = 1:N
                En(i,i)=i*pi/bsa;
                G3(i,i)=(Rt/Rsb)^(i*pi/bsa);
            end
            for i = 1:M
                for j = 1:N
                    en=j*pi/bsa;
                    fm=i*pi/boa;
                    gamma(i,j)=-(2/bsa)*(en/(fm^2-en^2))*(cos(i*pi)*sin(en*(bsa+boa)/2)-sin(en*(bsa-boa)/2));
                end
            end
            K97=gamma'*Fm;
            K98=-gamma'*Fm*G4;
            K99=-En*(G3^2-IN);
            K97i=eye(N*Ns,M*Ns);
            K98i=eye(N*Ns,M*Ns);
            K99i=eye(N*Ns,N*Ns);
            Ftm=eye(M*Ns,M*Ns);%%
            G4t=eye(M*Ns,M*Ns);%%
            for i = 1:N
                for j = 1:M
                    for n = 0:Ns-1
                        K97i(i+N*n,j+M*n)=K97(i,j);
                        K98i(i+N*n,j+M*n)=K98(i,j);
                    end
                end
            end
            for i = 1:N
                for j = 1:N
                    for n = 0:Ns-1
                        K99i(i+N*n,j+N*n)=K99(i,j);
                        Ftm(i+N*n,j+N*n)=Fm(i,j);
                        G4t(i+N*n,j+N*n)=G4(i,j);
                    end
                end
            end

            zeta=(bsa/boa)*gamma;
            IM=eye(M,M);
            K87i=eye(M*Ns,M*Ns);
            K88i=eye(M*Ns,M*Ns);
            K89i=eye(N*Ns,N*Ns);
            for i = 1:M
                for j = 1:M
                    for n = 0:Ns-1
                        K88i(i+M*n,j+M*n)=G4(i,j);
                    end
                end
            end
            K89=-zeta*(G3^2+IN);
            for i = 1:N
                for j = 1:N
                    for n = 0:Ns-1
                        K89i(i+N*n,j+N*n)=K89(i,j);
                    end
                end
            end

            %% slot opening and air gap 槽开口与气隙之间

            K53=-K;
            K65=-K;
            K54=G2*K;
            K66=G2*K;

            yita=zeros(k,M*Ns);
            kesei=zeros(k,M*Ns);

            for i = 1:M
                for j = 1:k
                    for n = 0:Ns-1
                        fm=i*pi/boa;
                        ai=theta_s/2+theta_s*n;
                        yita(j,i+M*n)=-(1/pi)*(j/(fm^2-j^2))*(cos(i*pi)*sin(j*ai+j*boa/2)-sin(j*ai-j*boa/2));
                        kesei(j,i+M*n)=(1/pi)*(j/(fm^2-j^2))*(cos(i*pi)*cos(j*ai+j*boa/2)-cos(j*ai-j*boa/2));
                    end
                end
            end

            K57=yita*Ftm*G4t;
            K58=-yita*Ftm;
            K67=kesei*Ftm*G4t;
            K68=-kesei*Ftm;

            sigma=(2*pi/boa)*yita';
            tao=(2*pi/boa)*kesei';
            K73=sigma;
            K74=sigma*G2;
            K75=tao;
            K76=tao*G2;
            K77=-G4t;
            K78=-eye(M*Ns,M*Ns);

            %% 拼凑系数矩阵

            Z1=zeros(k,k);
            Z2=zeros(k,M*Ns);
            Z3=zeros(M*Ns,k);
            Z4=zeros(M*Ns,M*Ns);

            ratio=[K11,Z1,K13,K14,Z1,Z1,Z2,Z2,Z2;
                Z1,K22,Z1,Z1,K25,K26,Z2,Z2,Z2;
                K31,Z1,K33,K34,Z1,Z1,Z2,Z2,Z2;
                Z1,K42,Z1,Z1,K45,K46,Z2,Z2,Z2;
                Z1,Z1,K53,K54,Z1,Z1,K57,K58,Z2;
                Z1,Z1,Z1,Z1,K65,K66,K67,K68,Z2;
                Z3,Z3,K73,K74,K75,K76,K77,K78,Z4;
                Z3,Z3,Z3,Z3,Z3,Z3,K87i,K88i,K89i;
                Z3,Z3,Z3,Z3,Z3,Z3,K97i,K98i,K99i];

            Y=[Y1;Y2;Y3;Y4;zeros(k*2+M*Ns*3,1)];

            %% 求出系数向量

            R=(ratio^(-1))*Y;

            A1=R(1:k,1);
            C1=R(k+1:2*k,1);
            A2=R(2*k+1:3*k,1);
            B2=R(3*k+1:4*k,1);
            C2=R(4*k+1:5*k,1);
            D2=R(5*k+1:6*k,1);
            C4t=R(6*k+1:6*k+M*Ns,1);
            D4t=R(6*k+M*Ns+1:6*k+M*Ns*2,1);
            D3t=R(6*k+M*Ns*2+1:6*k+M*Ns*3,1);

            %% 求气隙磁密分布

            r=0.074;     % r是所取的气隙半径，计算的是半径为r处的径向磁密
            accuracy=10000;
            alpha=linspace(-pi,4*pi,accuracy);
            B2r=zeros(accuracy,1);
            B2a=zeros(accuracy,1);
            for i = 1:accuracy
                level1=0;
                level2=0;
                level3=0;
                level4=0;
                for j = 1:2:k
                    level1=level1+j*((A2(j)/Rs)*(r/Rs)^(j-1)+(B2(j)/Rm)*(r/Rm)^(-j-1))*sin(j*alpha(i));
                    level2=level2+j*((C2(j)/Rs)*(r/Rs)^(j-1)+(D2(j)/Rm)*(r/Rm)^(-j-1))*cos(j*alpha(i));
                    level3=level3+j*((A2(j)/Rs)*(r/Rs)^(j-1)-(B2(j)/Rm)*(r/Rm)^(-j-1))*cos(j*alpha(i));
                    level4=level4+j*((C2(j)/Rs)*(r/Rs)^(j-1)-(D2(j)/Rm)*(r/Rm)^(-j-1))*sin(j*alpha(i));
                end
                B2r(i)=-level1+level2;
                B2a(i)=-level3-level4;
            end

            % subplot(1,2,1)
            plot(app.UIAxes3,180*(alpha-a0/4)/pi,B2r,'-k');
            xlim(app.UIAxes3,[0,720]);
            xlabel(app.UIAxes3,'角度/deg')
            ylabel(app.UIAxes3,'磁通密度/T')
            title(app.UIAxes3,"气隙径向磁通密度")
            hold(app.UIAxes3,'on');

            % subplot(1,2,2)
            plot(app.UIAxes3_2,180*(alpha-a0/4)/pi,B2a,'-r');
            xlim(app.UIAxes3_2,[0,720]);
            xlabel(app.UIAxes3_2,'角度/deg')
            ylabel(app.UIAxes3_2,'磁通密度/T')
            title(app.UIAxes3_2,"气隙切向磁通密度")
            hold(app.UIAxes3_2,'on');

            msgbox("计算完成！","提示框","help")
        end

        % Value changed function: EditField_1
        function EditField_1ValueChanged(app, event)
            Button_drawPushed(app, event);
            app.EditField_49.Value=app.EditField_2.Value;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_15
        function EditField_15ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_16
        function EditField_16ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_17
        function EditField_17ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_18
        function EditField_18ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_19
        function EditField_19ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_49
        function EditField_49ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_50
        function EditField_50ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_51
        function EditField_51ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_52
        function EditField_52ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_53
        function EditField_53ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_54
        function EditField_54ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_55
        function EditField_55ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_56
        function EditField_56ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_57
        function EditField_57ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_58
        function EditField_58ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_59
        function EditField_59ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_60
        function EditField_60ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_61
        function EditField_61ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_62
        function EditField_62ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_63
        function EditField_63ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_64
        function EditField_64ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_65
        function EditField_65ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_66
        function EditField_66ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_67
        function EditField_67ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_68
        function EditField_68ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changed function: EditField_69
        function EditField_69ValueChanged(app, event)
            ButtonPushed(app, event)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            ButtonPushed(app, event)
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            [filename, pathname] = uiputfile({'*.png'; '*.jpeg'; '*.bmp'}, 'Save Image As');
            imwrite(app.UIAxes.CDate,[pathname,filename]);
        end

        % Context menu opening function: ContextMenu2
        function ContextMenu2Opening(app, event)
            [filename, pathname] = uiputfile({'*.png'; '*.jpeg'; '*.bmp'}, 'Save Image As');
            imwrite(app.UIAxes.CDate,[pathname,filename]);
        end

        % Value changed function: EditField_70
        function EditField_70ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_71
        function EditField_71ValueChanged(app, event)
            Button_drawPushed(app, event);
        end

        % Value changed function: EditField_72
        function EditField_72ValueChanged(app, event)
            Button_drawPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '编辑';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Text = '模型';
            app.Menu_4 = uimenu(app.UIFigure);
            app.Menu_4.Text = '电机类型';
            app.Menu_5 = uimenu(app.UIFigure);
            app.Menu_5.Text = '设置';
            app.Menu_6 = uimenu(app.UIFigure);
            app.Menu_6.Text = '默认';
            app.Menu_7 = uimenu(app.UIFigure);
            app.Menu_7.Text = '编辑器';
            app.Menu_8 = uimenu(app.UIFigure);
            app.Menu_8.Text = '视图';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '几何';
            app.TabGroup2 = uitabgroup(app.Tab);
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '径向';
            app.UIAxes = uiaxes(app.Tab_4);
            app.UIAxes.Visible = 'off';
            app.Panel_3 = uipanel(app.Tab_4);
            app.Panel_3.Title = '转子参数';
            app.GridLayout2 = uigridlayout(app.Panel_3);
            app.Label_21 = uilabel(app.GridLayout2);
            app.Label_21.Text = '极数';
            app.Label_35 = uilabel(app.GridLayout2);
            app.Label_35.Text = '永磁体厚度';
            app.Label_36 = uilabel(app.GridLayout2);
            app.Label_36.Tooltip = {'永磁体边缘厚度削减'};
            app.Label_36.Text = '永磁体边缘厚度削减';
            app.MDLabel = uilabel(app.GridLayout2);
            app.MDLabel.Text = '永磁体弧长[MD]';
            app.Label_37 = uilabel(app.GridLayout2);
            app.Label_37.Text = '永磁体分段数';
            app.Label_38 = uilabel(app.GridLayout2);
            app.Label_38.Text = '气隙';
            app.Label_39 = uilabel(app.GridLayout2);
            app.Label_39.Text = '转子衬套厚度';
            app.Label_40 = uilabel(app.GridLayout2);
            app.Label_40.Text = '转轴直径';
            app.Label_41 = uilabel(app.GridLayout2);
            app.Label_41.Text = '轴孔直径';
            app.EditField_19 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_19.ValueChangedFcn = createCallbackFcn(app, @EditField_19ValueChanged, true);
            app.EditField_19.Value = 10;
            app.EditField_11 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_11.Value = 10;
            app.EditField_12 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_12.Value = 7.5;
            app.EditField_18 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_18.ValueChangedFcn = createCallbackFcn(app, @EditField_18ValueChanged, true);
            app.EditField_18.Value = 60;
            app.EditField_13 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.EditField_13.Tooltip = {''};
            app.EditField_14 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.EditField_14.Value = 38.17;
            app.EditField_17 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_17.ValueChangedFcn = createCallbackFcn(app, @EditField_17ValueChanged, true);
            app.EditField_15 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_15.ValueChangedFcn = createCallbackFcn(app, @EditField_15ValueChanged, true);
            app.EditField_15.Value = 4;
            app.EditField_16 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_16.ValueChangedFcn = createCallbackFcn(app, @EditField_16ValueChanged, true);
            app.EditField_16.Value = 4.5;
            app.Panel_2 = uipanel(app.Tab_4);
            app.Panel_2.Title = '定子参数';
            app.GridLayout = uigridlayout(app.Panel_2);
            app.Label_26 = uilabel(app.GridLayout);
            app.Label_26.Text = '定子铁心直径';
            app.Label_27 = uilabel(app.GridLayout);
            app.Label_27.Text = '定子内径';
            app.Label_28 = uilabel(app.GridLayout);
            app.Label_28.Text = '齿宽';
            app.Label_29 = uilabel(app.GridLayout);
            app.Label_29.Text = '槽深';
            app.Label_30 = uilabel(app.GridLayout);
            app.Label_30.Text = '槽圆角半径';
            app.Label_31 = uilabel(app.GridLayout);
            app.Label_31.Text = '定子槽肩高';
            app.Label_32 = uilabel(app.GridLayout);
            app.Label_32.Text = '槽开口';
            app.Label_33 = uilabel(app.GridLayout);
            app.Label_33.Text = '定子槽肩角';
            app.Label_34 = uilabel(app.GridLayout);
            app.Label_34.Text = '定子衬套厚度';
            app.EditField_10 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 230;
            app.EditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 150;
            app.EditField_9 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_9.Value = 30;
            app.EditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Value = 5.73;
            app.EditField_5 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_5.Value = 32.2;
            app.EditField_8 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_8.Value = 2.6;
            app.EditField_6 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_6.Value = 4;
            app.EditField_7 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 3.6;
            app.Label_11 = uilabel(app.GridLayout);
            app.Label_11.Text = '槽数';
            app.EditField_1 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_1.ValueChangedFcn = createCallbackFcn(app, @EditField_1ValueChanged, true);
            app.EditField_1.Value = 36;
            app.Panel_1 = uipanel(app.Tab_4);
            app.Label_22 = uilabel(app.Panel_1);
            app.Label_22.Text = '槽形:';
            app.DropDown = uidropdown(app.Panel_1);
            app.DropDown.Items = {'平行齿', '平行齿平底槽', '平行槽', '无槽', '绑扎', '锥形槽'};
            app.DropDown.Value = '平行齿平底槽';
            app.Label_23 = uilabel(app.Panel_1);
            app.Label_23.Text = '转子类型:';
            app.DropDown_2 = uidropdown(app.Panel_1);
            app.DropDown_2.Items = {'平行表贴', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown_2.Value = '平行表贴';
            app.Label_24 = uilabel(app.Panel_1);
            app.Label_24.Text = '定子管道:';
            app.DropDown_3 = uidropdown(app.Panel_1);
            app.Label_25 = uilabel(app.Panel_1);
            app.Label_25.Text = '转子管道:';
            app.DropDown_4 = uidropdown(app.Panel_1);
            app.Button_check = uibutton(app.Tab_4, 'push');
            app.Button_check.Text = '检查';
            app.Button_draw = uibutton(app.Tab_4, 'push');
            app.Button_draw.ButtonPushedFcn = createCallbackFcn(app, @Button_drawPushed, true);
            app.Button_draw.Text = '重绘';
            app.Panel_10 = uipanel(app.Tab_4);
            app.Panel_10.Title = '定子辅助槽';
            app.GridLayout8 = uigridlayout(app.Panel_10);
            app.Label_52 = uilabel(app.GridLayout8);
            app.Label_52.Text = '开槽数量';
            app.EditField_70 = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_70.ValueChangedFcn = createCallbackFcn(app, @EditField_70ValueChanged, true);
            app.EditField_70.Value = 3;
            app.EditField_71Label = uilabel(app.GridLayout8);
            app.EditField_71Label.Text = '辅助槽宽度';
            app.EditField_71 = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_71.ValueChangedFcn = createCallbackFcn(app, @EditField_71ValueChanged, true);
            app.EditField_71.Value = 1;
            app.Label_53 = uilabel(app.GridLayout8);
            app.Label_53.Text = '辅助槽深度';
            app.EditField_72 = uieditfield(app.GridLayout8, 'numeric');
            app.EditField_72.ValueChangedFcn = createCallbackFcn(app, @EditField_72ValueChanged, true);
            app.EditField_72.Value = 1;
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '轴向';
            app.UIAxes2 = uiaxes(app.Tab_5);
            app.UIAxes2.Visible = 'off';
            app.Panel_5 = uipanel(app.Tab_5);
            app.Panel_5.Title = '轴向尺寸';
            app.GridLayout4 = uigridlayout(app.Panel_5);
            app.Label_48 = uilabel(app.GridLayout4);
            app.Label_48.Text = '电机长度';
            app.EditField_59 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_59.ValueChangedFcn = createCallbackFcn(app, @EditField_59ValueChanged, true);
            app.EditField_59.Value = 220;
            app.EditField_60Label = uilabel(app.GridLayout4);
            app.EditField_60Label.Text = '定子铁心长';
            app.EditField_60 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_60.ValueChangedFcn = createCallbackFcn(app, @EditField_60ValueChanged, true);
            app.EditField_60.Value = 90;
            app.EditField_61Label = uilabel(app.GridLayout4);
            app.EditField_61Label.Text = '永磁体长度';
            app.EditField_61 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_61.ValueChangedFcn = createCallbackFcn(app, @EditField_61ValueChanged, true);
            app.EditField_61.Value = 90;
            app.EditField_62Label = uilabel(app.GridLayout4);
            app.EditField_62Label.Text = '永磁体分段数';
            app.EditField_62 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_62.ValueChangedFcn = createCallbackFcn(app, @EditField_62ValueChanged, true);
            app.EditField_62.Value = 4;
            app.EditField_63Label = uilabel(app.GridLayout4);
            app.EditField_63Label.Text = '转子铁心长';
            app.EditField_63 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_63.ValueChangedFcn = createCallbackFcn(app, @EditField_63ValueChanged, true);
            app.EditField_63.Value = 90;
            app.EditField_64Label = uilabel(app.GridLayout4);
            app.EditField_64Label.Text = '端部绕组悬垂[前部]';
            app.EditField_64 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_64.ValueChangedFcn = createCallbackFcn(app, @EditField_64ValueChanged, true);
            app.EditField_64.Value = 30;
            app.EditField_65Label = uilabel(app.GridLayout4);
            app.EditField_65Label.Text = '端部绕组悬垂[后部]';
            app.EditField_65 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_65.ValueChangedFcn = createCallbackFcn(app, @EditField_65ValueChanged, true);
            app.EditField_65.Value = 30;
            app.EditField_66Label = uilabel(app.GridLayout4);
            app.EditField_66Label.Text = '端部绕组直线长[前]';
            app.EditField_66 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_66.ValueChangedFcn = createCallbackFcn(app, @EditField_66ValueChanged, true);
            app.EditField_66.Value = 5;
            app.EditField_67Label = uilabel(app.GridLayout4);
            app.EditField_67Label.Text = '端部绕组直线长[后]';
            app.EditField_67 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_67.ValueChangedFcn = createCallbackFcn(app, @EditField_67ValueChanged, true);
            app.EditField_67.Value = 5;
            app.Label_49 = uilabel(app.GridLayout4);
            app.Label_49.Text = '转轴伸出长[前]';
            app.EditField_68 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_68.ValueChangedFcn = createCallbackFcn(app, @EditField_68ValueChanged, true);
            app.Label_50 = uilabel(app.GridLayout4);
            app.Label_50.Text = '转轴伸出长[后]';
            app.EditField_69 = uieditfield(app.GridLayout4, 'numeric');
            app.EditField_69.ValueChangedFcn = createCallbackFcn(app, @EditField_69ValueChanged, true);
            app.Panel_4 = uipanel(app.Tab_5);
            app.Panel_4.Title = '径向尺寸';
            app.GridLayout3 = uigridlayout(app.Panel_4);
            app.Label_44 = uilabel(app.GridLayout3);
            app.Label_44.Text = '定子铁心直径';
            app.EditField_49 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_49.ValueChangedFcn = createCallbackFcn(app, @EditField_49ValueChanged, true);
            app.EditField_49.Value = 230;
            app.EditField_50Label = uilabel(app.GridLayout3);
            app.EditField_50Label.Text = '定子内径';
            app.EditField_50 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_50.ValueChangedFcn = createCallbackFcn(app, @EditField_50ValueChanged, true);
            app.EditField_50.Value = 150;
            app.EditField_51Label = uilabel(app.GridLayout3);
            app.EditField_51Label.Text = '气隙';
            app.EditField_51 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_51.ValueChangedFcn = createCallbackFcn(app, @EditField_51ValueChanged, true);
            app.EditField_51.Value = 4.5;
            app.Label_45 = uilabel(app.GridLayout3);
            app.Label_45.Text = '转子衬套厚度';
            app.EditField_52 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_52.ValueChangedFcn = createCallbackFcn(app, @EditField_52ValueChanged, true);
            app.Label_46 = uilabel(app.GridLayout3);
            app.Label_46.Text = '定子衬套厚度';
            app.EditField_53 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_53.ValueChangedFcn = createCallbackFcn(app, @EditField_53ValueChanged, true);
            app.EditField_54Label = uilabel(app.GridLayout3);
            app.EditField_54Label.Text = '永磁体厚度';
            app.EditField_54 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_54.ValueChangedFcn = createCallbackFcn(app, @EditField_54ValueChanged, true);
            app.EditField_54.Value = 7.5;
            app.EditField_55Label = uilabel(app.GridLayout3);
            app.EditField_55Label.Text = '转轴直径';
            app.EditField_55 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_55.ValueChangedFcn = createCallbackFcn(app, @EditField_55ValueChanged, true);
            app.EditField_55.Value = 60;
            app.EditField_56Label = uilabel(app.GridLayout3);
            app.EditField_56Label.Text = '前转轴直径';
            app.EditField_56 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_56.ValueChangedFcn = createCallbackFcn(app, @EditField_56ValueChanged, true);
            app.EditField_56.Value = 25;
            app.Label_47 = uilabel(app.GridLayout3);
            app.Label_47.Text = '后转轴直径';
            app.EditField_57 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_57.ValueChangedFcn = createCallbackFcn(app, @EditField_57ValueChanged, true);
            app.EditField_57.Value = 25;
            app.EditField_58Label = uilabel(app.GridLayout3);
            app.EditField_58Label.Text = '轴孔直径';
            app.EditField_58 = uieditfield(app.GridLayout3, 'numeric');
            app.EditField_58.ValueChangedFcn = createCallbackFcn(app, @EditField_58ValueChanged, true);
            app.EditField_58.Value = 10;
            app.Panel_6 = uipanel(app.Tab_5);
            app.Label_42 = uilabel(app.Panel_6);
            app.Label_42.Text = '转轴类型:';
            app.DropDown_5 = uidropdown(app.Panel_6);
            app.DropDown_5.Items = {'固体', '蜘蛛轴'};
            app.DropDown_5.Value = '固体';
            app.Label_43 = uilabel(app.Panel_6);
            app.Label_43.Text = '径向通风管道:';
            app.DropDown_6 = uidropdown(app.Panel_6);
            app.DropDown_6.Items = {'无', '转子和定子', '转子', '定子'};
            app.DropDown_6.Value = '无';
            app.Button = uibutton(app.Tab_5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '重绘';
            app.Label_51 = uilabel(app.Tab_5);
            app.Label_51.Text = '绕组端部圆角半径';
            app.Slider = uislider(app.Tab_5);
            app.Slider.Limits = [0 10];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 8;
            app.Tab_6 = uitab(app.TabGroup2);
            app.Tab_6.Title = '三维';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '绕组';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '数据输入';
            app.Panel_7 = uipanel(app.Tab_3);
            app.GridLayout5 = uigridlayout(app.Panel_7);
            app.Label_14 = uilabel(app.GridLayout5);
            app.Label_14.Text = '永磁体相对磁导率';
            app.EditField_45 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_45.Value = 1.05;
            app.Label_15 = uilabel(app.GridLayout5);
            app.Label_15.Text = '永磁体剩磁';
            app.EditField_46 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_46.Value = 1.16;
            app.Label_13 = uilabel(app.GridLayout5);
            app.Label_13.Text = '真空磁导率';
            app.EditField_44 = uieditfield(app.GridLayout5, 'numeric');
            app.EditField_44.Value = 1.25663706143592e-06;
            app.Panel_8 = uipanel(app.Tab_3);
            app.GridLayout6 = uigridlayout(app.Panel_8);
            app.Label_17 = uilabel(app.GridLayout6);
            app.Label_17.Text = '转子转动时刻';
            app.EditField_48 = uieditfield(app.GridLayout6, 'numeric');
            app.Label_16 = uilabel(app.GridLayout6);
            app.Label_16.Text = '转速';
            app.EditField_47 = uieditfield(app.GridLayout6, 'numeric');
            app.EditField_47.Value = 20000;
            app.Panel_9 = uipanel(app.Tab_3);
            app.GridLayout7 = uigridlayout(app.Panel_9);
            app.Label_18 = uilabel(app.GridLayout7);
            app.Label_18.Text = '气隙及磁极谐波次数';
            app.EditField_34 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_34.Value = 100;
            app.Label_19 = uilabel(app.GridLayout7);
            app.Label_19.Text = '齿槽谐波次数';
            app.EditField_35 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_35.Value = 100;
            app.Label_20 = uilabel(app.GridLayout7);
            app.Label_20.Text = '谐波次数';
            app.EditField_36 = uieditfield(app.GridLayout7, 'numeric');
            app.EditField_36.Value = 100;
            app.Button_2 = uibutton(app.Tab_3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '计算';
            app.Tab_9 = uitab(app.TabGroup);
            app.Tab_9.Title = '图表';
            app.TabGroup4 = uitabgroup(app.Tab_9);
            app.Tab_10 = uitab(app.TabGroup4);
            app.Tab_10.Title = '气隙磁密(径向)';
            app.UIAxes3 = uiaxes(app.Tab_10);
            app.Tab_11 = uitab(app.TabGroup4);
            app.Tab_11.Title = '气隙磁密(周向)';
            app.UIAxes3_2 = uiaxes(app.Tab_11);
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.ContextMenu2.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu2Opening, true);
            app.Menu_10 = uimenu(app.ContextMenu2);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '保存图片';
            % Assign app.ContextMenu2
            app.UIAxes.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end